classdef CartesienneGeographique < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        secEditField_2Label             matlab.ui.control.Label
        secEditField_2                  matlab.ui.control.NumericEditField
        secEditFieldLabel               matlab.ui.control.Label
        secEditField                    matlab.ui.control.NumericEditField
        hEditField                      matlab.ui.control.NumericEditField
        hEditFieldLabel                 matlab.ui.control.Label
        degEditField_2                  matlab.ui.control.NumericEditField
        degEditField_2Label             matlab.ui.control.Label
        minEditField_2                  matlab.ui.control.NumericEditField
        minEditField_2Label             matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        degEditField                    matlab.ui.control.NumericEditField
        degEditFieldLabel               matlab.ui.control.Label
        ZEditField                      matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        inv_fEditField                  matlab.ui.control.NumericEditField
        inv_fEditFieldLabel             matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        HauteurLabel                    matlab.ui.control.Label
        LongitudeLabel                  matlab.ui.control.Label
        LatitudeLabel                   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        LescoordonnesgographiquesLabel  matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        EntrerlescoordonnescartsiennesLabel  matlab.ui.control.Label
        EntrerlesvaleursLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        NadirSoukainaLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            a=app.aEditField.Value;
            inv_f=app.inv_fEditField.Value;
            X=app.XEditField.Value;
            Y=app.YEditField.Value;
            Z=app.ZEditField.Value;
            [lat,long,h]=coordonnees_cartesiennes_geographiques(a,inv_f,X,Y,Z);
            app.degEditField.Value=lat(1);
            app.minEditField.Value=lat(2);
            app.secEditField.Value=lat(3);
            app.degEditField_2.Value=long(1);
            app.minEditField_2.Value=long(2);
            app.secEditField_2.Value=long(3);            
            app.hEditField.Value=h;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NadirSoukainaLabel = uilabel(app.UIFigure);
            app.NadirSoukainaLabel.Text = 'Nadir Soukaina ';
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'terre1.png';
            app.EntrerlesvaleursLabel = uilabel(app.UIFigure);
            app.EntrerlesvaleursLabel.Text = 'Entrer les valeurs : ';
            app.EntrerlescoordonnescartsiennesLabel = uilabel(app.UIFigure);
            app.EntrerlescoordonnescartsiennesLabel.Text = 'Entrer les coordonneés cartésiennes : ';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'fleche.png';
            app.Button.Text = '';
            app.LescoordonnesgographiquesLabel = uilabel(app.UIFigure);
            app.LescoordonnesgographiquesLabel.Text = 'Les coordonneés géographiques : ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Transformation des coordonnées cartesiennes aux coordonnées geographiques (direct)';
            app.LatitudeLabel = uilabel(app.UIFigure);
            app.LatitudeLabel.Text = 'Latitude';
            app.LongitudeLabel = uilabel(app.UIFigure);
            app.LongitudeLabel.Text = 'Longitude';
            app.HauteurLabel = uilabel(app.UIFigure);
            app.HauteurLabel.Text = 'Hauteur';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.inv_fEditFieldLabel = uilabel(app.UIFigure);
            app.inv_fEditFieldLabel.Text = 'inv_f';
            app.inv_fEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X = ';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y = ';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z = ';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.degEditFieldLabel = uilabel(app.UIFigure);
            app.degEditFieldLabel.Text = 'deg';
            app.degEditField = uieditfield(app.UIFigure, 'numeric');
            app.minEditFieldLabel = uilabel(app.UIFigure);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.UIFigure, 'numeric');
            app.minEditField_2Label = uilabel(app.UIFigure);
            app.minEditField_2Label.Text = 'min';
            app.minEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.degEditField_2Label = uilabel(app.UIFigure);
            app.degEditField_2Label.Text = 'deg';
            app.degEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.UIFigure, 'numeric');
            app.secEditField = uieditfield(app.UIFigure, 'numeric');
            app.secEditFieldLabel = uilabel(app.UIFigure);
            app.secEditFieldLabel.Text = 'sec';
            app.secEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.secEditField_2Label = uilabel(app.UIFigure);
            app.secEditField_2Label.Text = 'sec';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CartesienneGeographique

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end