classdef CartographiqueGeographique < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        EntrerlesvaleursLabel           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        EntrerlescoordonnescartographiquesLabel  matlab.ui.control.Label
        LescoordonnesgographiquesLabel  matlab.ui.control.Label
        lat0Label                       matlab.ui.control.Label
        long0Label                      matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        LatitudeLabel                   matlab.ui.control.Label
        LongitudeLabel                  matlab.ui.control.Label
        NadirSoukainaLabel              matlab.ui.control.Label
        aEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        inv_fEditFieldLabel             matlab.ui.control.Label
        inv_fEditField                  matlab.ui.control.NumericEditField
        K0EditFieldLabel                matlab.ui.control.Label
        K0EditField                     matlab.ui.control.NumericEditField
        degEditFieldLabel               matlab.ui.control.Label
        degEditField                    matlab.ui.control.NumericEditField
        minEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.NumericEditField
        secEditFieldLabel               matlab.ui.control.Label
        secEditField                    matlab.ui.control.NumericEditField
        secEditField_2Label             matlab.ui.control.Label
        secEditField_2                  matlab.ui.control.NumericEditField
        minEditField_2Label             matlab.ui.control.Label
        minEditField_2                  matlab.ui.control.NumericEditField
        degEditField_2Label             matlab.ui.control.Label
        degEditField_2                  matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        degEditField_3Label             matlab.ui.control.Label
        degEditField_3                  matlab.ui.control.NumericEditField
        minEditField_3Label             matlab.ui.control.Label
        minEditField_3                  matlab.ui.control.NumericEditField
        secEditField_3Label             matlab.ui.control.Label
        secEditField_3                  matlab.ui.control.NumericEditField
        secEditField_4Label             matlab.ui.control.Label
        secEditField_4                  matlab.ui.control.NumericEditField
        minEditField_4Label             matlab.ui.control.Label
        minEditField_4                  matlab.ui.control.NumericEditField
        degEditField_4Label             matlab.ui.control.Label
        degEditField_4                  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            a=app.aEditField.Value;
            inv_f=app.inv_fEditField.Value;
            K0=app.K0EditField.Value;
            lat0=[app.degEditField.Value app.minEditField.Value app.secEditField.Value];
            long0=[app.degEditField_2.Value app.minEditField_2.Value app.secEditField_2.Value];
            Xproj=app.XEditField.Value;
            Yproj=app.YEditField.Value;
            [lat,long]= coordonnees_cartographies_geographies(a,inv_f,K0,lat0,long0,Xproj,Yproj);
            app.degEditField_3.Value=lat(1);
            app.minEditField_3.Value=lat(2);
            app.secEditField_3.Value=lat(3);
            app.degEditField_4.Value=long(1);
            app.minEditField_4.Value=long(2);
            app.secEditField_4.Value=long(3);             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'terre1.png';
            app.EntrerlesvaleursLabel = uilabel(app.UIFigure);
            app.EntrerlesvaleursLabel.Text = 'Entrer les valeurs : ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Transformation des coordonnées cartographiques aux coordonnées geographiques ';
            app.EntrerlescoordonnescartographiquesLabel = uilabel(app.UIFigure);
            app.EntrerlescoordonnescartographiquesLabel.Text = 'Entrer les coordonneés cartographiques : ';
            app.LescoordonnesgographiquesLabel = uilabel(app.UIFigure);
            app.LescoordonnesgographiquesLabel.Text = 'Les coordonneés géographiques : ';
            app.lat0Label = uilabel(app.UIFigure);
            app.lat0Label.Text = 'lat0';
            app.long0Label = uilabel(app.UIFigure);
            app.long0Label.Text = 'long0';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'fleche.png';
            app.Button.Text = '';
            app.LatitudeLabel = uilabel(app.UIFigure);
            app.LatitudeLabel.Text = 'Latitude';
            app.LongitudeLabel = uilabel(app.UIFigure);
            app.LongitudeLabel.Text = 'Longitude';
            app.NadirSoukainaLabel = uilabel(app.UIFigure);
            app.NadirSoukainaLabel.Text = 'Nadir Soukaina ';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.inv_fEditFieldLabel = uilabel(app.UIFigure);
            app.inv_fEditFieldLabel.Text = 'inv_f';
            app.inv_fEditField = uieditfield(app.UIFigure, 'numeric');
            app.K0EditFieldLabel = uilabel(app.UIFigure);
            app.K0EditFieldLabel.Text = 'K0';
            app.K0EditField = uieditfield(app.UIFigure, 'numeric');
            app.degEditFieldLabel = uilabel(app.UIFigure);
            app.degEditFieldLabel.Text = 'deg';
            app.degEditField = uieditfield(app.UIFigure, 'numeric');
            app.minEditFieldLabel = uilabel(app.UIFigure);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.UIFigure, 'numeric');
            app.secEditFieldLabel = uilabel(app.UIFigure);
            app.secEditFieldLabel.Text = 'sec';
            app.secEditField = uieditfield(app.UIFigure, 'numeric');
            app.secEditField_2Label = uilabel(app.UIFigure);
            app.secEditField_2Label.Text = 'sec';
            app.secEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.minEditField_2Label = uilabel(app.UIFigure);
            app.minEditField_2Label.Text = 'min';
            app.minEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.degEditField_2Label = uilabel(app.UIFigure);
            app.degEditField_2Label.Text = 'deg';
            app.degEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y =';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X = ';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.degEditField_3Label = uilabel(app.UIFigure);
            app.degEditField_3Label.Text = 'deg';
            app.degEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.minEditField_3Label = uilabel(app.UIFigure);
            app.minEditField_3Label.Text = 'min';
            app.minEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.secEditField_3Label = uilabel(app.UIFigure);
            app.secEditField_3Label.Text = 'sec';
            app.secEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.secEditField_4Label = uilabel(app.UIFigure);
            app.secEditField_4Label.Text = 'sec';
            app.secEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.minEditField_4Label = uilabel(app.UIFigure);
            app.minEditField_4Label.Text = 'min';
            app.minEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.degEditField_4Label = uilabel(app.UIFigure);
            app.degEditField_4Label.Text = 'deg';
            app.degEditField_4 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CartographiqueGeographique

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end