classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image               matlab.ui.control.Image
        ConvertirButton_5   matlab.ui.control.Button
        NadirSoukainaLabel  matlab.ui.control.Label
        QuitterButton       matlab.ui.control.StateButton
        VeuillezchoisirletypedetransformationquevousvoulezLabel  matlab.ui.control.Label
        Label2_3            matlab.ui.control.Label
        Label2_2            matlab.ui.control.Label
        Label_3             matlab.ui.control.Label
        Label_2             matlab.ui.control.Label
        Label2              matlab.ui.control.Label
        Label               matlab.ui.control.Label
        ConvertirButton_4   matlab.ui.control.Button
        ConvertirButton_3   matlab.ui.control.Button
        IndirectButton      matlab.ui.control.Button
        DirectButton        matlab.ui.control.Button
        MenuLabel           matlab.ui.control.Label
        Image2              matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConvertirButton_5
        function ConvertirButtonPushed(app, event)
            close(app.UIFigure);
            run('GeographiqueCartesienne.mlapp');
        end

        % Button pushed function: DirectButton
        function DirectButton_3Pushed(app, event)
            close(app.UIFigure);
            run('CartesienneGeographique.mlapp');            
        end

        % Button pushed function: IndirectButton
        function ConvertirButton_4Pushed(app, event)
            close(app.UIFigure);
            run('CartesienneGeographiqueIndirect.mlapp');            
        end

        % Button pushed function: ConvertirButton_3
        function ConvertirButton_2Pushed(app, event)
            close(app.UIFigure);
            run('GeographiqueCartographique.mlapp');            
        end

        % Button pushed function: ConvertirButton_4
        function ConvertirButton_5Pushed(app, event)
            close(app.UIFigure);
            run('CartographiqueGeographique.mlapp');            
        end

        % Value changed function: QuitterButton
        function QuitterButtonValueChanged(app, event)
              close (app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Enable = 'off';
            app.Image2.ImageSource = 'stock-vector-wind-rose-compass.jpg';
            app.MenuLabel = uilabel(app.UIFigure);
            app.MenuLabel.Text = 'Menu';
            app.DirectButton = uibutton(app.UIFigure, 'push');
            app.DirectButton.ButtonPushedFcn = createCallbackFcn(app, @DirectButton_3Pushed, true);
            app.DirectButton.Text = 'Direct';
            app.IndirectButton = uibutton(app.UIFigure, 'push');
            app.IndirectButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButton_4Pushed, true);
            app.IndirectButton.Text = 'Indirect';
            app.ConvertirButton_3 = uibutton(app.UIFigure, 'push');
            app.ConvertirButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButton_2Pushed, true);
            app.ConvertirButton_3.Text = '2) Transformation des coordonnées geographiques aux coordonnées cartographiques ';
            app.ConvertirButton_4 = uibutton(app.UIFigure, 'push');
            app.ConvertirButton_4.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButton_5Pushed, true);
            app.ConvertirButton_4.Text = '3) Transformation de coordonnés cartographique  vers les coordonneés géographique';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = '4) Transformation des coordonnées cartesiennes aux coordonnées geographiques : ';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label2_2 = uilabel(app.UIFigure);
            app.Label2_2.Text = '';
            app.Label2_3 = uilabel(app.UIFigure);
            app.Label2_3.Text = '';
            app.VeuillezchoisirletypedetransformationquevousvoulezLabel = uilabel(app.UIFigure);
            app.VeuillezchoisirletypedetransformationquevousvoulezLabel.Text = 'Veuillez choisir le type de transformation que vous voulez : ';
            app.QuitterButton = uibutton(app.UIFigure, 'state');
            app.QuitterButton.ValueChangedFcn = createCallbackFcn(app, @QuitterButtonValueChanged, true);
            app.QuitterButton.Text = 'Quitter ';
            app.NadirSoukainaLabel = uilabel(app.UIFigure);
            app.NadirSoukainaLabel.Text = 'Nadir Soukaina ';
            app.ConvertirButton_5 = uibutton(app.UIFigure, 'push');
            app.ConvertirButton_5.ButtonPushedFcn = createCallbackFcn(app, @ConvertirButtonPushed, true);
            app.ConvertirButton_5.Text = ' 1) Transformation des coordonnées géographiques aux coordonnées cartésiennes';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'fst-tanger-couleur.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end